classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        PlotButton      matlab.ui.control.Button
        L1SliderLabel   matlab.ui.control.Label
        L1Slider        matlab.ui.control.Slider
        L2SliderLabel   matlab.ui.control.Label
        L2Slider        matlab.ui.control.Slider
        L3SliderLabel   matlab.ui.control.Label
        L3Slider        matlab.ui.control.Slider
        Q1minKnobLabel  matlab.ui.control.Label
        Q1minKnob       matlab.ui.control.Knob
        Q2minKnobLabel  matlab.ui.control.Label
        Q2minKnob       matlab.ui.control.Knob
        Q3minKnobLabel  matlab.ui.control.Label
        Q3minKnob       matlab.ui.control.Knob
        Q1maxKnobLabel  matlab.ui.control.Label
        Q1maxKnob       matlab.ui.control.Knob
        Q2maxKnobLabel  matlab.ui.control.Label
        Q2maxKnob       matlab.ui.control.Knob
        Q3maxKnobLabel  matlab.ui.control.Label
        Q3maxKnob       matlab.ui.control.Knob
    end


    properties (Access = private)
        L1 = 0;
        L2 = 0;
        L3 = 0;
        Q1_min = 0;
        Q1_max = 0;
        Q2_min = 0;
        Q2_max = 0;
        Q3_min = 0;
        Q3_max = 0;
    end


    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.L1=app.L1Slider.Value;
            app.L2=app.L2Slider.Value;
            app.L3=app.L3Slider.Value;
            app.Q1_min=app.Q1minKnob.Value;
            app.Q1_max=app.Q1maxKnob.Value;
            app.Q2_min=app.Q2minKnob.Value;
            app.Q2_max=app.Q2maxKnob.Value;
            app.Q3_min=app.Q3minKnob.Value;
            app.Q3_max=app.Q3maxKnob.Value;
            t1=linspace(app.Q1_min, app.Q1_max, 100)*pi/180;
            t2=linspace(app.Q2_min, app.Q2_max, 100)*pi/180;
            t3=linspace(app.Q3_min, app.Q3_max, 100)*pi/180;
            [q1,q2,q3]=ndgrid(t1,t2,t3);  % This will create matrices of 120*120*120 for each variable
            xM = app.L1 * cos(q1) + app.L2 * cos(q1 + q2) + app.L3 * cos(q1 + q2 + q3);
            yM = app.L1 * sin(q1) + app.L2 * sin(q1 + q2) + app.L3 * sin(q1 + q2 + q3);
            plot(app.UIAxes,xM(:),yM(:));
%             xL = xlim; %all values of x
%             yL = ylim; %all values of y
%             line(app.UIAxes,xL, [0 0],'color','k','linewidth',1)  %Draw x-axis
%             line(app.UIAxes,[0 0], yL,'color','k','linewidth',1)  %Draw y-axis
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot WorkSpace');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.L1SliderLabel = uilabel(app.UIFigure);
            app.L1SliderLabel.Text = 'L1';
            app.L1Slider = uislider(app.UIFigure);
            app.L1Slider.Value = 50;
            app.L2SliderLabel = uilabel(app.UIFigure);
            app.L2SliderLabel.Text = 'L2';
            app.L2Slider = uislider(app.UIFigure);
            app.L2Slider.Value = 50;
            app.L3SliderLabel = uilabel(app.UIFigure);
            app.L3SliderLabel.Text = 'L3';
            app.L3Slider = uislider(app.UIFigure);
            app.L3Slider.Value = 50;
            app.Q1minKnobLabel = uilabel(app.UIFigure);
            app.Q1minKnobLabel.Text = 'Q1 min';
            app.Q1minKnob = uiknob(app.UIFigure, 'continuous');
            app.Q1minKnob.Limits = [0 360];
            app.Q2minKnobLabel = uilabel(app.UIFigure);
            app.Q2minKnobLabel.Text = 'Q2 min';
            app.Q2minKnob = uiknob(app.UIFigure, 'continuous');
            app.Q2minKnob.Limits = [0 360];
            app.Q3minKnobLabel = uilabel(app.UIFigure);
            app.Q3minKnobLabel.Text = 'Q3 min';
            app.Q3minKnob = uiknob(app.UIFigure, 'continuous');
            app.Q3minKnob.Limits = [0 360];
            app.Q1maxKnobLabel = uilabel(app.UIFigure);
            app.Q1maxKnobLabel.Text = 'Q1 max';
            app.Q1maxKnob = uiknob(app.UIFigure, 'continuous');
            app.Q1maxKnob.Limits = [0 360];
            app.Q1maxKnob.Value = 90;
            app.Q2maxKnobLabel = uilabel(app.UIFigure);
            app.Q2maxKnobLabel.Text = 'Q2 max';
            app.Q2maxKnob = uiknob(app.UIFigure, 'continuous');
            app.Q2maxKnob.Limits = [0 360];
            app.Q2maxKnob.Value = 90;
            app.Q3maxKnobLabel = uilabel(app.UIFigure);
            app.Q3maxKnobLabel.Text = 'Q3 max';
            app.Q3maxKnob = uiknob(app.UIFigure, 'continuous');
            app.Q3maxKnob.Limits = [0 360];
            app.Q3maxKnob.Value = 90;
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end